classdef octfrangi_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewBatchMenu                 matlab.ui.container.Menu
        LoadBatchMenu                matlab.ui.container.Menu
        SaveBatchMenu                matlab.ui.container.Menu
        RunBatchMenu                 matlab.ui.container.Menu
        CloseBatchEditorMenu         matlab.ui.container.Menu
        ModuleMenu                   matlab.ui.container.Menu
        IMGimportMenu                matlab.ui.container.Menu
        AttenuationCorrectionMenu    matlab.ui.container.Menu
        BinarizeMenu                 matlab.ui.container.Menu
        DifferenceMenu               matlab.ui.container.Menu
        ComplementMenu               matlab.ui.container.Menu
        FrangiFilterMenu             matlab.ui.container.Menu
        IntensityAdjustmentMenu      matlab.ui.container.Menu
        MaskMenu                     matlab.ui.container.Menu
        NormalizeMenu                matlab.ui.container.Menu
        ResizeMenu                   matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        ModuleMenu_2                 matlab.ui.container.Menu
        MoveUpSelectedModuleMenu     matlab.ui.container.Menu
        MoveDownSelectedModuleMenu   matlab.ui.container.Menu
        RemovetheSelectedModuleMenu  matlab.ui.container.Menu
        RemoveAllModulesMenu         matlab.ui.container.Menu
        CopytheSelectedModuleMenu    matlab.ui.container.Menu
        SpecifytheSelectedItemMenu   matlab.ui.container.Menu
        UtilMenu                     matlab.ui.container.Menu
        CreateMaskMenu               matlab.ui.container.Menu
        DispImageMenu                matlab.ui.container.Menu
        NIfTIMenu                    matlab.ui.container.Menu
        TIFMenu                      matlab.ui.container.Menu
        IMGMenu                      matlab.ui.container.Menu
        DispImageInfoMenu            matlab.ui.container.Menu
        NIfTIMenu_2                  matlab.ui.container.Menu
        TIFMenu_2                    matlab.ui.container.Menu
        DirOperationsMenu            matlab.ui.container.Menu
        ShowCurrentDirectoryMenu     matlab.ui.container.Menu
        ChangeWorkingDirectoryMenu   matlab.ui.container.Menu
        CurrentItemLabel             matlab.ui.control.Label
        Tree3                        matlab.ui.container.Tree
        SpecifyButton                matlab.ui.control.Button
        CurrentModuleLabel           matlab.ui.control.Label
        Tree2                        matlab.ui.container.Tree
        ModuleListLabel              matlab.ui.control.Label
        Tree                         matlab.ui.container.Tree
        INFOTextArea                 matlab.ui.control.TextArea
        INFOTextAreaLabel            matlab.ui.control.Label
        CurrentItemTextArea          matlab.ui.control.TextArea
        CurrentItemTextAreaLabel     matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        UpMenu                       matlab.ui.container.Menu
        DownMenu                     matlab.ui.container.Menu
        RemoveMenu                   matlab.ui.container.Menu
        CopyMenu                     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        matlabbatch % a variable to be saved
        moduleOptsMap % Module-Option mapping
    end
    
    methods (Static)
        function results = module_option_mapping()
            modules = {'IMG import',...
                       'Resize',...
                       'Mask',...
                       'Attenuation Correction',...
                       'Normalize',...
                       'Binarize',...
                       'Complement',...
                       'Frangi Filter',...
                       'Intensity Adjustment',...
                       'Difference'};
            options = {[{'Input image file(s)', 'No selected'}; {'Input volume size [H*W*Slices]', [1536 500 500]}; {'Output directory', '.'}; {'Output format', 'nii'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'Output size [H*W]', [256 500]}; {'Prefix', 'r'}],...
                       [{'Input image file(s)', 'No selected'}; {'Mask directory', 'No selected'}; {'Output directory', '.'}; {'Prefix', 'm'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'Prefix', 'a'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'Norm Range [H]', [1 1200]}; {'Norm Range [W]', [1 500]}; {'Norm Range [D]', [1 500]}; {'Prefix', 'n'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'Global threshold', 0.1}; {'Prefix', 'b'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'Prefix', 'c'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'FrangiScaleRange', [1 2]}; {'FrangiScaleRatio', 1}; {'FrangiAlpha', 0.5}; {'FrangiBeta', 0.5}; {'FrangiC', 500}; {'Prefix', 'f'}],...
                       [{'Input image file(s)', 'No selected'}; {'Output directory', '.'}; {'range_in [low high]', [0 1]}; {'range_out [low high]', [0 1]}; {'Prefix', 'i'}],...
                       [{'Minuend image file', 'No selected'}; {'Subtrahend image file', 'No selected'}; {'Output file', 'No selected'}]};
            results = containers.Map(modules, options);
        end
    end
    
    methods (Access = private)
                
        function results = clear_component_contents(app)
            clear_module_list(app);
            clear_current_module(app);
            clear_current_item(app);
        end
        
        function results = clear_module_list(app)
            a = app.Tree.Children; a.delete; % clear module list
        end
        
        function results = clear_current_module(app)
            a = app.Tree2.Children; a.delete;
        end
        
        function results = clear_current_item(app)
            app.CurrentItemTextArea.Value = ''; % TODO: 削除予定
            a = app.Tree3.Children; a.delete;
        end
        
        function results = clear_all_variables(app)
            app.matlabbatch = {};
        end
        
        function Mout = add_new_module(app, moduleName)
            Mout = containers.Map();
            Mout('id') = length(app.matlabbatch) + 1;
            Mout('moduleName') = moduleName;
            switch moduleName
                case 'IMG import'
                    moduleOpts = app.moduleOptsMap('IMG import');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Resize'
                    moduleOpts = app.moduleOptsMap('Resize');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Mask'
                    moduleOpts = app.moduleOptsMap('Mask');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Attenuation Correction'
                    moduleOpts = app.moduleOptsMap('Attenuation Correction');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Normalize'
                    moduleOpts = app.moduleOptsMap('Normalize');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Binarize'
                    moduleOpts = app.moduleOptsMap('Binarize');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Complement'
                    moduleOpts = app.moduleOptsMap('Complement');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Frangi Filter'
                    moduleOpts = app.moduleOptsMap('Frangi Filter');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Intensity Adjustment'
                    moduleOpts = app.moduleOptsMap('Intensity Adjustment');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                case 'Difference'
                    moduleOpts = app.moduleOptsMap('Difference');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        optKey = moduleOpts{iOpt, 1};
                        optVal = moduleOpts{iOpt, 2};
                        Mout(optKey) = optVal;
                    end
                otherwise
            end
        end
        
        function results = show_module_list(app)
            clear_component_contents(app);
            numModules = length(app.matlabbatch);
            for iModule = 1:numModules
                uitreenode(app.Tree, 'Text', app.matlabbatch{iModule}('moduleName'));
                app.Tree.Children(iModule).NodeData = iModule;
            end
        end
        
        function reulsts = show_current_module(app)
            switch app.Tree.SelectedNodes.Text
                case 'IMG import'
                    moduleOpts = app.moduleOptsMap('IMG import');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Resize'
                    moduleOpts = app.moduleOptsMap('Resize');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Mask'
                    moduleOpts = app.moduleOptsMap('Mask');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Attenuation Correction'
                    moduleOpts = app.moduleOptsMap('Attenuation Correction');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Normalize'
                    moduleOpts = app.moduleOptsMap('Normalize');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Binarize'
                    moduleOpts = app.moduleOptsMap('Binarize');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Complement'
                    moduleOpts = app.moduleOptsMap('Complement');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Frangi Filter'
                    moduleOpts = app.moduleOptsMap('Frangi Filter');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Intensity Adjustment'
                    moduleOpts = app.moduleOptsMap('Intensity Adjustment');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(app.Tree2, 'Text', moduleOpts{iOpt, 1});
                    end
                case 'Difference'
                    data1 = uitreenode(app.Tree2, 'Text', 'Data');
                otherwise
            end
        end
           
        function show_current_item(app, selectedModuleRank, selectedOptName)
            if strcmp(app.matlabbatch{selectedModuleRank}('moduleName'), 'Difference')
                app.Tree3.Children.delete;
                selectedNode = app.Tree2.SelectedNodes.Text;
                if strcmp(selectedNode, 'Data')
                    uitreenode(app.Tree3, 'Text', '<.>');
                    uitreenode(app.Tree3, 'Text', 'New Session');
                elseif contains(selectedNode, 'Session')
                    uitreenode(app.Tree3, 'Text', '<.>');
                    uitreenode(app.Tree3, 'Text', 'New Session');
                    uitreenode(app.Tree3, 'Text', 'Delete Session');
%                     uitreenode(app.Tree3, 'Text', 'Copy Session');
                elseif strcmp(selectedNode, 'Minuend image file') || strcmp(selectedNode, 'Subtrahend image file') || strcmp(selectedNode, 'Output file')
                    val = app.matlabbatch{selectedModuleRank}(selectedNode);
                    uitreenode(app.Tree3, 'Text', val);
                end
            else
                % Get the value
                val = app.matlabbatch{selectedModuleRank}(selectedOptName);
                % Display the value
                if isnumeric(val), val = num2str(val); end
                app.CurrentItemTextArea.Value = val;
            end
        end
        
        function results = get_selected_module_rank(app)
            currentModuleId = app.Tree.SelectedNodes.NodeData;
            moduleIdList = [app.Tree.Children.NodeData];
            results = find(moduleIdList == currentModuleId);
        end
        
        function results = get_input_image_files(app, selectedModuleRank)
            selectedModuleName = app.matlabbatch{selectedModuleRank}('moduleName');
            switch selectedModuleName
                case 'IMG import'
                    [file, path] = uigetfile({'*.img';'*.tif;*.tiff'}, 'Select File(s)', 'MultiSelect','on');
                otherwise
                    [file, path] = uigetfile('*.nii', 'Select File(s)', 'MultiSelect','on');
            end
            if length(file) == 0
                % a process in the case of NOT-SELECTED-FILE, here
                results = app.CurrentItemTextArea.Value;
            else
                if not(iscell(file))
                    file = cellstr(file);
                end
                path = cellstr(path);
                path = repmat(path, size(file));
                results = append(path, file);
            end
        end
        
        function results = specify_input_image_files(app, selectedModuleRank, selectedOptName)
            % Get path to data
            file_path = get_input_image_files(app, selectedModuleRank);
            set_current_item(app, file_path, selectedModuleRank, selectedOptName);
        end
        
        function results = specify_output_directory(app, selectedModuleRank, selectedOptName)
            % Get output directory
            outDir = uigetdir('.', 'Select Output Directory');
            if outDir == 0
                outDir = app.CurrentItemTextArea.Value;
            end
            set_current_item(app, outDir, selectedModuleRank, selectedOptName);
        end
        
        function results = specify_prefix(app, selectedModuleRank, selectedOptName)
            % Get output directory
            pf = inputdlg('Enter prefix:', 'Input', [1 35], app.CurrentItemTextArea.Value);
            if isempty(pf)
                prefix = app.CurrentItemTextArea.Value;
            else
                prefix = pf;
            end
            set_current_item(app, prefix{:}, selectedModuleRank, selectedOptName);
        end
        
        function results = set_current_item(app, val, selectedModuleRank, selectedOptName)
            % Assing the value
            if strcmp(app.matlabbatch{selectedModuleRank}('moduleName'), 'Difference')
                % TODO: PROCESS
            else
                app.matlabbatch{selectedModuleRank}(selectedOptName) = val;
            end
            % Display the value
            if isnumeric(val), val = num2str(val); end
%             app.CurrentItemTextArea.Value = val;
            app.Tree3.Children.Text = val;
        end
        
        function results = move_up_selected_module(app)
            selectedModuleRank = get_selected_module_rank(app);
            if selectedModuleRank >= 2
                tmp = app.matlabbatch;
                app.matlabbatch{selectedModuleRank - 1} = app.matlabbatch{selectedModuleRank};
                app.matlabbatch{selectedModuleRank} = tmp{selectedModuleRank - 1};
            end
        end
        
        function results = move_down_selected_module(app)
            selectedModuleRank = get_selected_module_rank(app);
            if selectedModuleRank < length(app.matlabbatch)
                tmp = app.matlabbatch;
                app.matlabbatch{selectedModuleRank + 1} = app.matlabbatch{selectedModuleRank};
                app.matlabbatch{selectedModuleRank} = tmp{selectedModuleRank + 1};
            end
        end
        
        function results = remove_selected_module(app)
            if not(isempty(app.matlabbatch))
                selectedModuleRank = get_selected_module_rank(app);
                if not(isempty(selectedModuleRank))
                    numModules = length(app.matlabbatch);
                    tmp = app.matlabbatch;
                    app.matlabbatch = {};
                    ii = 0;
                    for iModule = 1:numModules
                        if not(iModule == selectedModuleRank)
                            ii = ii + 1;
                            app.matlabbatch{ii} = tmp{iModule};
                        end
                    end
                end
            end
        end
        
        function results = copy_selected_module(app)
            selectedModuleRank = get_selected_module_rank(app);
            maxId = 0;
            for ii = 1:length(app.matlabbatch)
                if app.matlabbatch{ii}('id') > maxId, maxId = app.matlabbatch{ii}('id'); end
            end
            keySet = keys(app.matlabbatch{selectedModuleRank});
            valueSet = values(app.matlabbatch{selectedModuleRank});
            M = containers.Map(keySet, valueSet);
            M('id') = maxId + 1;
            app.matlabbatch{end + 1} = M;
            
        end
        
        function results = run_module(app, iModule)
            currentModuleName = app.matlabbatch{iModule}('moduleName');
            currentModuleId = iModule;
            switch currentModuleName
                case 'IMG import'
                    run_img_import(app, currentModuleId);
                case 'Resize'
                    run_resize(app, currentModuleId);
                case 'Mask'
                    run_mask(app, currentModuleId);
                case 'Attenuation Correction'
                    run_atten_cor(app, currentModuleId);
                case 'Normalize'
                    run_norm(app, currentModuleId);
                case 'Binarize'
                    run_binarize(app, currentModuleId);
                case 'Complement'
                    run_complement(app, currentModuleId);
                case 'Frangi Filter'
                    run_frangi(app, currentModuleId);
                case 'Intensity Adjustment'
                    run_intensity_adjust(app, currentModuleId);
                case 'Difference'
                    run_diff(app, currentModuleId);
            end
        end
        
        function results = run_img_import(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                [~, ~, ext] = fileparts(inputFileName);
                if or(strcmp(ext, '.tif'), strcmp(ext, '.tiff'))
                    A = read_tif(app, inputFileName);
                else % for img file
                    inputVolSize = app.matlabbatch{currentModuleId}('Input volume size [H*W*Slices]');
                    A = read_img(app, inputFileName, inputVolSize);
                end
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2uint8(A), outFileName);
            end
        end
        
        function results = read_tif(~, fileName)
            t = Tiff(fileName, "r");
            results(:,:,1) = t.read();
            t.nextDirectory();
            while true
                results(:,:,end+1) = t.read();
                if t.lastDirectory(), break; end
                t.nextDirectory();
            end
            close(t);
        end
        
        function results = read_img(~, fileName, volSize)
            AScRes = volSize(1); % Height
            BScH = volSize(2); % Width
            BScV = volSize(3); % # Images
            fid = fopen(fileName,'r');
            results = fread(fid,inf,'*uint8');
            fclose(fid);
            % Process
            results = reshape(results, BScH, AScRes, BScV); %A = reshape(A,BScH,AScRes,[]);
            results = permute(results, [2,1,3]);
            for i=1:BScV
                results(:,:,i)=rot90(squeeze(results(:,:,i)),2);
            end
        end
        
        function results = run_resize(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            outputSize = app.matlabbatch{currentModuleId}('Output size [H*W]');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                V = im2double(niftiread(inputFileName));
                % Process
                A = imresize(V, outputSize);
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2uint8(A), outFileName);
            end
        end
        
        function results = run_mask(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            maskDir = app.matlabbatch{currentModuleId}('Mask directory');
            maskFiles = dir(fullfile(maskDir, 'mask_*_cube_z.nii'));
            maskFiles = {maskFiles.name};
            numMaskFiles = length(maskFiles);
            for iMask = 1:numMaskFiles
                maskFileName = fullfile(maskDir, maskFiles{iMask});
                maskV = logical(niftiread(maskFileName));
                f = erase(maskFiles{iMask}, ["mask_", "_cube_z.nii"]);
                if any(contains(inputFiles, f))
                    targetFileName = inputFiles{contains(inputFiles, f)};
                    targetV = im2double(niftiread(targetFileName));
                    results = immultiply(targetV, maskV); %targetV .* maskV;
                    % save the result
                    outFileName = set_output_file_name(app, currentModuleId, targetFileName);
                    save_vol_as_nii(app, im2uint8(results), outFileName);
                end
            end
        end
        
        function results = run_atten_cor(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                V = im2double(niftiread(inputFileName));
                % Attenuation Correction
                imgHeight = size(V, 1);
                D2 = V .* V;
                D2z_sum = zeros(size(V));
                D2z_sum(1,:,:) = sum(D2) - D2(1,:,:);
                for row = 2:imgHeight
                    D2z_sum(row,:,:) = D2z_sum(row-1,:,:) - D2(row,:,:);
                end
                Dcorr_atten = D2./(2*D2z_sum+1);
                % save results
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2double(Dcorr_atten), outFileName);
            end
        end
        
        function results = run_norm(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            normRangeH = app.matlabbatch{currentModuleId}('Norm Range [H]');
            normRangeW = app.matlabbatch{currentModuleId}('Norm Range [W]');
            normRangeD = app.matlabbatch{currentModuleId}('Norm Range [D]');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                V = im2double(niftiread(inputFileName));
                % Normalize
                maxVal = max(max(max(V(normRangeH, normRangeW, normRangeD))));
                V_norm = V / maxVal;
                V_norm(V_norm > 1) = 0;
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2double(V_norm), outFileName);
            end
        end
        
        function results = run_binarize(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            thr = app.matlabbatch{currentModuleId}('Global threshold');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                V = im2double(niftiread(inputFileName));
                % Process
                A = imbinarize(V, thr);
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2uint8(A), outFileName);
            end
        end
        
        function results = run_complement(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                V = im2uint8(niftiread(inputFileName)); % uint8
                % Process
                A = imcomplement(V);
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2uint8(A), outFileName);
            end
        end
        
        function results = run_frangi(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            options.FrangiScaleRange = app.matlabbatch{currentModuleId}('FrangiScaleRange');
            options.FrangiScaleRatio = app.matlabbatch{currentModuleId}('FrangiScaleRatio');
            options.FrangiAlpha = app.matlabbatch{currentModuleId}('FrangiAlpha');
            options.FrangiBeta = app.matlabbatch{currentModuleId}('FrangiBeta');
            options.FrangiC = app.matlabbatch{currentModuleId}('FrangiC');
            numInputFiles = length(inputFiles);
            fprintf("\n");
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                disp(inputFileName);
                V = im2double(niftiread(inputFileName));
                % Process
                A = FrangiFilter3D(V, options);
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2double(A), outFileName);
            end
        end
        
        function results = run_intensity_adjust(app, currentModuleId)
            inputFiles = app.matlabbatch{currentModuleId}('Input image file(s)');
            range_in = app.matlabbatch{currentModuleId}('range_in [low high]');
            range_out = app.matlabbatch{currentModuleId}('range_out [low high]');
            numInputFiles = length(inputFiles);
            for iFile = 1:numInputFiles
                inputFileName = inputFiles{iFile};
                V = im2double(niftiread(inputFileName));
                % Process
                A = imadjustn(V, range_in, range_out);
                outFileName = set_output_file_name(app, currentModuleId, inputFileName);
                save_vol_as_nii(app, im2double(A), outFileName);
            end
        end
        
        function results = run_diff(app, currentModuleId)
            minuendFile = app.matlabbatch{currentModuleId}('Minuend image file');
            minuendV = im2double(niftiread(minuendFile));
            subtrahendFile = app.matlabbatch{currentModuleId}('Subtrahend image file');
            subtrahendV = im2double(niftiread(subtrahendFile));
            A = imsubtract(minuendV, subtrahendV);
            save_vol_as_nii(app, A, app.matlabbatch{currentModuleId}('Output file'))
        end
        
        function results = set_output_file_name(app, currentModuleId, inputFileName)
            outPath = app.matlabbatch{currentModuleId}('Output directory');
            if isKey(app.matlabbatch{currentModuleId}, 'Prefix')
                prefix = app.matlabbatch{currentModuleId}('Prefix');
            else
                prefix = '';
            end
            [~, outFile] = fileparts(inputFileName);
            if isKey(app.matlabbatch{currentModuleId}, 'Output format')
                outExt = app.matlabbatch{currentModuleId}('Output format');
            else
                outExt = 'nii';
            end
            results = fullfile(outPath, [prefix outFile '.' outExt]);
        end
        
        function results = save_vol_as_nii(~, vol, savefile)
%             saveVol = im2uint8(vol);
            niftiwrite(vol, savefile);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            clear_all_variables(app);
            app.moduleOptsMap = octfrangi_menu.module_option_mapping;
%             app.moduleOptsMap = module_option_mapping(app);
        end

        % Menu selected function: NewBatchMenu, RemoveAllModulesMenu
        function select_new_batch(app, event)
            clear_component_contents(app);
            clear_all_variables(app);
        end

        % Menu selected function: LoadBatchMenu
        function select_load_batch(app, event)
            % clear
            clear_component_contents(app);
            clear_all_variables(app);
            % Get a batch file
            [file, path] = uigetfile('*.mat', 'Select Batch File');
            if file == 0
                % Nothing's gonna happen.
            else
                load(fullfile(path, file), 'matlabbatch');
                app.matlabbatch = matlabbatch;
                % Show Module List onto Tree
                show_module_list(app);
            end
            % Focus App again
            figure(app.UIFigure);
        end

        % Menu selected function: SaveBatchMenu
        function select_save_batch(app, event)
            matlabbatch = app.matlabbatch;
            [file, path] = uiputfile('*.mat', 'Save batch');
            if file == 0
                % Nothing' gonna happen.
            else
                save(fullfile(path, file), 'matlabbatch');
            end
            % Focus App again
            figure(app.UIFigure);
        end

        % Menu selected function: RunBatchMenu
        function select_run_batch(app, event)
            numModules = length(app.matlabbatch);
            for iModule = 1:numModules
                fprintf([num2str(iModule) '/' num2str(numModules) ': ' app.matlabbatch{iModule}('moduleName') '...']);
                run_module(app, iModule);
                disp('Done');
            end
        end

        % Menu selected function: IMGimportMenu
        function select_img_import(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'IMG import');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'IMG import', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: ResizeMenu
        function select_resize(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Resize');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Resize', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: MaskMenu
        function select_mask(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Mask');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Mask', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: AttenuationCorrectionMenu
        function select_att_corr(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Attenuation Correction');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Attenuation Correction', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: NormalizeMenu
        function select_norm(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Normalize');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Normalize', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Callback function
        function select_grayscale(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Grayscale');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Grayscale', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: BinarizeMenu
        function select_binarize(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Binarize');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Binarize', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: ComplementMenu
        function select_complement(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Complement');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Complement', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: FrangiFilterMenu
        function select_frangi(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Frangi Filter');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Frangi Filter', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: IntensityAdjustmentMenu
        function select_intensity_adjust(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Intensity Adjustment');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Intensity Adjustment', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: DifferenceMenu
        function select_diff(app, event)
            % Add new module
            app.matlabbatch{end+1} = add_new_module(app, 'Difference');
            % Set Tag
            numModules = length(app.matlabbatch);
            % Show the module on the list
            uitreenode(app.Tree, 'Text', 'Difference', "NodeData", numModules);
            % Clear 'Current Module'
            a = app.Tree2.Children; a.delete;
        end

        % Menu selected function: CreateMaskMenu
        function select_create_mask(app, event)
            % Select Mat-file(s)
            [files, path] = uigetfile('*.mat', 'Select File(s)', 'MultiSelect','on');
            if not(iscell(files)), files = cellstr(files); end
            % Set Output Directory
            outDir = uigetdir(".", "Select Output Directory");
            for iFile = 1:length(files)
                load(fullfile(path, files{iFile}), "Mask");
                % save the mask volume as NIfTI
                niftiwrite(Mask, fullfile(outDir, ['mask_' strrep(files{iFile}, '.mat', '.nii')]));
                clearvars Mask;
            end
        end

        % Menu selected function: NIfTIMenu
        function select_disp_nifti(app, event)
            [file, path] = uigetfile('*.nii', 'Select File');
            V = niftiread(fullfile(path, file));
            orthosliceViewer(V);
        end

        % Menu selected function: TIFMenu
        function select_disp_tif(app, event)
            [file, path] = uigetfile('*.tif;*.tiff', 'Select File');
            V = read_tif(app, fullfile(path, file));
            orthosliceViewer(V);
        end

        % Menu selected function: IMGMenu
        function select_disp_img(app, event)
            [file, path] = uigetfile('*.img;*.tiff', 'Select File');
            inputVolSize = inputdlg({'Enter Height [pixles]:', 'Enter Width [pixels]:', 'Enter Num. of Slice [slices]'}, 'Input');
            V = read_img(app, fullfile(path, file), [str2double(inputVolSize{1}) str2double(inputVolSize{2}) str2double(inputVolSize{3})]);
            orthosliceViewer(V);
        end

        % Menu selected function: NIfTIMenu_2
        function select_disp_info_nifti(app, event)
            [file, path] = uigetfile('*.nii', 'Select File');
            info = niftiinfo(fullfile(path, file));
            fields = fieldnames(info);  
            numField = length(fields);
            c = {};
            for iField = 1:numField
                val = getfield(info, fields{iField});
                if ischar(val)
                    val = cellstr(val);
                    c(end+1) = append(fields(iField), ': ', val); 
                elseif isnumeric(val)
                    val = num2str(val);
                    c(end+1) = append(fields(iField), ': ', val); 
                end
            end
            app.INFOTextArea.Value = c;
            figure(app.UIFigure);
        end

        % Menu selected function: TIFMenu_2
        function select_disp_info_tif(app, event)
            [file, path] = uigetfile({'*.tif;*.tiff'}, 'Select File');
            t = Tiff(fullfile(path, file));
            c = {};
            c{1} = append('IFF File: ', t.FileName);
            c{2} = append('Number of Strips: ', num2str(t.numberOfStrips));
            c{3} = append('Image Length: ', num2str(getTag(t, 'ImageLength')));
            c{4} = append('Image Width: ', num2str(getTag(t, 'ImageWidth')));
            c{5} = append('RowsPerStrip: ', num2str(getTag(t, 'RowsPerStrip')));
            c{6} = append('BitsPerSample: ', num2str(getTag(t, 'BitsPerSample')));
            close(t);
            app.INFOTextArea.Value = c;
            figure(app.UIFigure);
        end

        % Menu selected function: ShowCurrentDirectoryMenu
        function select_show_current_dir(app, event)
            currentDir = pwd;
            app.INFOTextArea.Value = currentDir;
        end

        % Menu selected function: ChangeWorkingDirectoryMenu
        function select_change_work_dir(app, event)
            selPath = uigetdir('.', 'Select a Working Directory');
            cd(selPath);
            figure(app.UIFigure);
        end

        % Menu selected function: MoveUpSelectedModuleMenu, UpMenu
        function select_context_menu_up(app, event)
            move_up_selected_module(app);
            show_module_list(app);
        end

        % Menu selected function: DownMenu, 
        % MoveDownSelectedModuleMenu
        function select_context_menu_down(app, event)
            move_down_selected_module(app);
            show_module_list(app);
        end

        % Menu selected function: RemoveMenu, 
        % RemovetheSelectedModuleMenu
        function select_context_menu_remove(app, event)
            remove_selected_module(app);
            show_module_list(app);
        end

        % Menu selected function: CopyMenu, 
        % CopytheSelectedModuleMenu
        function select_context_menu_copy(app, event)
            copy_selected_module(app);
            show_module_list(app);
        end

        % Selection changed function: Tree3
        function select_module_opt(app, event)
            selectedNodes = app.Tree3.SelectedNodes;
            switch selectedNodes.Text
                case 'New Session'
                    if length(app.Tree2.Children.Children) == 0
                        numSessions = 1;
                    else
                        numSessions = str2double(cell2mat(extract(app.Tree2.Children.Children(end).Text, digitsPattern))) + 1;
                    end
                    session = uitreenode(app.Tree2.Children, 'Text', ['Session(' num2str(numSessions) '):']);
                    moduleOpts = app.moduleOptsMap('Difference');
                    numOpts = size(moduleOpts, 1);
                    for iOpt = 1:numOpts
                        uitreenode(session, 'Text', moduleOpts{iOpt, 1});
                    end
                    app.Tree2.Children.expand;
                case 'Delete Session'
                    if contains(app.Tree2.SelectedNodes.Text, 'Session')
                        app.Tree2.SelectedNodes.delete;
                        app.Tree2.SelectedNodes = app.Tree2.Children; % return focus to Parent
                        app.Tree3.Children(3).delete; % Delete "Delete Session"
                    end
%                 case 'Copy Session'
%                     disp('Copy');
            end
        end

        % Selection changed function: Tree
        function ModuleSelectionChanged(app, event)
            clear_current_module(app);
            clear_current_item(app);
            show_current_module(app);
        end

        % Selection changed function: Tree2
        function ModuleOptSelectionChanged(app, event)
            selectedModuleRank = get_selected_module_rank(app);
            selectedOptName = app.Tree2.SelectedNodes.Text;
            show_current_item(app, selectedModuleRank, selectedOptName)
        end

        % Callback function: SpecifyButton, SpecifytheSelectedItemMenu
        function SpecifyButtonPushed(app, event)
            selectedModuleRank = get_selected_module_rank(app);
            selectedOptName = app.Tree2.SelectedNodes.Text;
            switch selectedOptName
                % Shared
                case 'Input image file(s)'
                    specify_input_image_files(app, selectedModuleRank, selectedOptName);
                case 'Output directory'
                    specify_output_directory(app, selectedModuleRank, selectedOptName);
                case 'Prefix'
                    specify_prefix(app, selectedModuleRank, selectedOptName);
                % For 'IMG import'
                case 'Input volume size [H*W*Slices]'
                    prompt = {'Enter Height [pixles]:', 'Enter Width [pixels]:', 'Enter Num. of Slice [slices]'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    volSize = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(volSize)
                        volSize = definput;
                    end
                    volSize = [str2double(volSize{1}) str2double(volSize{2}) str2double(volSize{3})];
                    set_current_item(app, volSize, selectedModuleRank, selectedOptName);
                case 'Output format'
                    app.INFOTextArea.Value = '!!Only NIfTI (.nii) is available!!';
                % For 'Resize'
                case 'Output size [H*W]'
                    prompt = {'Enter Height [pixels]:', 'Enter Width [pixels]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    outSize = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(outSize)
                        outSize = definput;
                    end
                    outSize = [str2double(outSize{1}) str2double(outSize{2})];
                    set_current_item(app, outSize, selectedModuleRank, selectedOptName);
                % For 'Mask'
                case 'Mask directory'
                    maskDir = uigetdir('.', 'Select Mask Directory');
                    if maskDir == 0
                        maskDir = app.CurrentItemTextArea.Value;
                    end
                    set_current_item(app, maskDir, selectedModuleRank, selectedOptName);
                % For 'Attenuation Correction'
                % For 'Normalize'
                case 'Norm Range [H]'
                    prompt = {'Norm Range Height [FROM]:', 'Norm Range Height [TO]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    normRangeH = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(normRangeH)
                        normRangeH = definput;
                    end
                    normRangeH = [str2double(normRangeH{1}) str2double(normRangeH{2})];
                    set_current_item(app, normRangeH, selectedModuleRank, selectedOptName);
                case 'Norm Range [W]'
                    prompt = {'Norm Range Width [FROM]:', 'Norm Range Width [TO]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    normRangeW = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(normRangeW)
                        normRangeW = definput;
                    end
                    normRangeW = [str2double(normRangeW{1}) str2double(normRangeW{2})];
                    set_current_item(app, normRangeW, selectedModuleRank, selectedOptName);
                case 'Norm Range [D]'
                    prompt = {'Norm Range Slices [FROM]:', 'Norm Range Slices [TO]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    normRangeD = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(normRangeD)
                        normRangeD = definput;
                    end
                    normRangeD = [str2double(normRangeD{1}) str2double(normRangeD{2})];
                    set_current_item(app, normRangeD, selectedModuleRank, selectedOptName);
                % For 'Binarize'
                case 'Global threshold'
                    definput = app.CurrentItemTextArea.Value;
                    globalThr = inputdlg('Enter Global Threshold:', 'Input', [1 35], definput);
                    if isempty(globalThr)
                        globalThr = definput;
                    end
                    set_current_item(app, str2double(globalThr), selectedModuleRank, selectedOptName);
                % For 'Complement'
                % For 'Frangi Filter'
                case 'FrangiScaleRange'
                    prompt = {'Frangi Scale Range [FROM]:', 'Frangi Scale Range [TO]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    frangiScaleRange = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(frangiScaleRange)
                        frangiScaleRange = definput;
                    end
                    frangiScaleRange = [str2double(frangiScaleRange{1}) str2double(frangiScaleRange{2})];
                    set_current_item(app, frangiScaleRange, selectedModuleRank, selectedOptName);
                case 'FrangiScaleRatio'
                    definput = app.CurrentItemTextArea.Value;
                    frangiScaleRatio = inputdlg('Frangi Scale Ratio:', 'Input', [1 35], definput);
                    if isempty(frangiScaleRatio)
                        frangiScaleRatio = definput;
                    end
                    set_current_item(app, str2double(frangiScaleRatio), selectedModuleRank, selectedOptName);
                case 'FrangiAlpha'
                    definput = app.CurrentItemTextArea.Value;
                    frangiAlpha = inputdlg('Frangi Alpha:', 'Input', [1 35], definput);
                    if isempty(frangiAlpha)
                        frangiAlpha = definput;
                    end
                    set_current_item(app, str2double(frangiAlpha), selectedModuleRank, selectedOptName);
                case 'FrangiBeta'
                    definput = app.CurrentItemTextArea.Value;
                    frangiBeta = inputdlg('Frangi Beta:', 'Input', [1 35], definput);
                    if isempty(frangiBeta)
                        frangiBeta = definput;
                    end
                    set_current_item(app, str2double(frangiBeta), selectedModuleRank, selectedOptName);
                case 'FrangiC'
                    definput = app.CurrentItemTextArea.Value;
                    frangiC = inputdlg('Frangi C:', 'Input', [1 35], definput);
                    if isempty(frangiC)
                        frangiC = definput;
                    end
                    set_current_item(app, str2double(frangiC), selectedModuleRank, selectedOptName);
                % For 'Intensity Adjustment'
                case 'range_in [low high]'
                    prompt = {'Range_in [low]:', 'Range_in [high]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    range_in = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(range_in)
                        range_in = definput;
                    end
                    range_in = [str2double(range_in{1}) str2double(range_in{2})];
                    set_current_item(app, range_in, selectedModuleRank, selectedOptName);
                case 'range_out [low high]'
                    prompt = {'Range_out [low]:', 'Range_out [high]:'};
                    definput = strsplit(app.CurrentItemTextArea.Value{:}, ' ');
                    range_out = inputdlg(prompt, 'Input', [1 35], definput);
                    if isempty(range_out)
                        range_out = definput;
                    end
                    range_out = [str2double(range_out{1}) str2double(range_out{2})];
                    set_current_item(app, range_out, selectedModuleRank, selectedOptName);
                % For 'Difference'
                case 'Minuend image file'
                    [minuendFile, minuendPath] = uigetfile('*.nii', 'Select a Minuend Image');
                    if minuendFile == 0
%                         path_to_file = app.CurrentItemTextArea.Value;
                        path_to_file = app.Tree3.Children.Text;
                    else
                        path_to_file = fullfile(minuendPath, minuendFile);
                    end
                    set_current_item(app, path_to_file, selectedModuleRank, selectedOptName);
                case 'Subtrahend image file'
                    [subtrahendFile, subtrahendPath] = uigetfile('*.nii', 'Select a Subtrahend Image');
                    if subtrahendFile == 0
%                         path_to_file = app.CurrentItemTextArea.Value;
                        path_to_file = app.Tree3.Children.Text;
                    else
                        path_to_file = fullfile(subtrahendPath, subtrahendFile);
                    end
                    set_current_item(app, path_to_file, selectedModuleRank, selectedOptName);
                case 'Output file'
                    currentModuleRank = get_selected_module_rank(app);
                    minuend = app.matlabbatch{currentModuleRank}('Minuend image file');
                    [p, f] = fileparts(minuend);
                    [outFile, outPath] = uiputfile('*.nii', 'Save Result', fullfile(p, ['diff_', f, '.nii']));
                    set_current_item(app, fullfile(outPath, outFile), selectedModuleRank, selectedOptName);
            end
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewBatchMenu = uimenu(app.FileMenu);
            app.NewBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @select_new_batch, true);
            app.NewBatchMenu.Text = 'New Batch';
            app.LoadBatchMenu = uimenu(app.FileMenu);
            app.LoadBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @select_load_batch, true);
            app.LoadBatchMenu.Text = 'Load Batch';
            app.SaveBatchMenu = uimenu(app.FileMenu);
            app.SaveBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @select_save_batch, true);
            app.SaveBatchMenu.Text = 'Save Batch';
            app.RunBatchMenu = uimenu(app.FileMenu);
            app.RunBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @select_run_batch, true);
            app.RunBatchMenu.Text = 'Run Batch';
            app.CloseBatchEditorMenu = uimenu(app.FileMenu);
            app.CloseBatchEditorMenu.Text = 'Close Batch Editor';
            app.ModuleMenu = uimenu(app.UIFigure);
            app.ModuleMenu.Text = 'Module';
            app.IMGimportMenu = uimenu(app.ModuleMenu);
            app.IMGimportMenu.MenuSelectedFcn = createCallbackFcn(app, @select_img_import, true);
            app.IMGimportMenu.Text = 'IMG import';
            app.AttenuationCorrectionMenu = uimenu(app.ModuleMenu);
            app.AttenuationCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @select_att_corr, true);
            app.AttenuationCorrectionMenu.Text = 'Attenuation Correction';
            app.BinarizeMenu = uimenu(app.ModuleMenu);
            app.BinarizeMenu.MenuSelectedFcn = createCallbackFcn(app, @select_binarize, true);
            app.BinarizeMenu.Text = 'Binarize';
            app.DifferenceMenu = uimenu(app.ModuleMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @select_diff, true);
            app.DifferenceMenu.Text = 'Difference';
            app.ComplementMenu = uimenu(app.ModuleMenu);
            app.ComplementMenu.MenuSelectedFcn = createCallbackFcn(app, @select_complement, true);
            app.ComplementMenu.Text = 'Complement';
            app.FrangiFilterMenu = uimenu(app.ModuleMenu);
            app.FrangiFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @select_frangi, true);
            app.FrangiFilterMenu.Text = 'Frangi Filter';
            app.IntensityAdjustmentMenu = uimenu(app.ModuleMenu);
            app.IntensityAdjustmentMenu.MenuSelectedFcn = createCallbackFcn(app, @select_intensity_adjust, true);
            app.IntensityAdjustmentMenu.Text = 'Intensity Adjustment';
            app.MaskMenu = uimenu(app.ModuleMenu);
            app.MaskMenu.MenuSelectedFcn = createCallbackFcn(app, @select_mask, true);
            app.MaskMenu.Text = 'Mask';
            app.NormalizeMenu = uimenu(app.ModuleMenu);
            app.NormalizeMenu.MenuSelectedFcn = createCallbackFcn(app, @select_norm, true);
            app.NormalizeMenu.Text = 'Normalize';
            app.ResizeMenu = uimenu(app.ModuleMenu);
            app.ResizeMenu.MenuSelectedFcn = createCallbackFcn(app, @select_resize, true);
            app.ResizeMenu.Text = 'Resize';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ModuleMenu_2 = uimenu(app.EditMenu);
            app.ModuleMenu_2.Text = 'Module';
            app.MoveUpSelectedModuleMenu = uimenu(app.ModuleMenu_2);
            app.MoveUpSelectedModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_up, true);
            app.MoveUpSelectedModuleMenu.Text = 'Move Up Selected Module';
            app.MoveDownSelectedModuleMenu = uimenu(app.ModuleMenu_2);
            app.MoveDownSelectedModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_down, true);
            app.MoveDownSelectedModuleMenu.Text = 'Move Down Selected Module';
            app.RemovetheSelectedModuleMenu = uimenu(app.ModuleMenu_2);
            app.RemovetheSelectedModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_remove, true);
            app.RemovetheSelectedModuleMenu.Text = 'Remove the Selected Module';
            app.RemoveAllModulesMenu = uimenu(app.ModuleMenu_2);
            app.RemoveAllModulesMenu.MenuSelectedFcn = createCallbackFcn(app, @select_new_batch, true);
            app.RemoveAllModulesMenu.Text = 'Remove All Modules';
            app.CopytheSelectedModuleMenu = uimenu(app.ModuleMenu_2);
            app.CopytheSelectedModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_copy, true);
            app.CopytheSelectedModuleMenu.Text = 'Copy the Selected Module';
            app.SpecifytheSelectedItemMenu = uimenu(app.EditMenu);
            app.SpecifytheSelectedItemMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecifyButtonPushed, true);
            app.SpecifytheSelectedItemMenu.Text = 'Specify the Selected Item';
            app.UtilMenu = uimenu(app.UIFigure);
            app.UtilMenu.Text = 'Util';
            app.CreateMaskMenu = uimenu(app.UtilMenu);
            app.CreateMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @select_create_mask, true);
            app.CreateMaskMenu.Text = 'Create Mask';
            app.DispImageMenu = uimenu(app.UtilMenu);
            app.DispImageMenu.Text = 'Disp Image';
            app.NIfTIMenu = uimenu(app.DispImageMenu);
            app.NIfTIMenu.MenuSelectedFcn = createCallbackFcn(app, @select_disp_nifti, true);
            app.NIfTIMenu.Text = 'NIfTI';
            app.TIFMenu = uimenu(app.DispImageMenu);
            app.TIFMenu.MenuSelectedFcn = createCallbackFcn(app, @select_disp_tif, true);
            app.TIFMenu.Text = 'TIF';
            app.IMGMenu = uimenu(app.DispImageMenu);
            app.IMGMenu.MenuSelectedFcn = createCallbackFcn(app, @select_disp_img, true);
            app.IMGMenu.Text = 'IMG';
            app.DispImageInfoMenu = uimenu(app.UtilMenu);
            app.DispImageInfoMenu.Text = 'Disp Image Info';
            app.NIfTIMenu_2 = uimenu(app.DispImageInfoMenu);
            app.NIfTIMenu_2.MenuSelectedFcn = createCallbackFcn(app, @select_disp_info_nifti, true);
            app.NIfTIMenu_2.Text = 'NIfTI';
            app.TIFMenu_2 = uimenu(app.DispImageInfoMenu);
            app.TIFMenu_2.MenuSelectedFcn = createCallbackFcn(app, @select_disp_info_tif, true);
            app.TIFMenu_2.Text = 'TIF';
            app.DirOperationsMenu = uimenu(app.UtilMenu);
            app.DirOperationsMenu.Text = 'Dir Operations';
            app.ShowCurrentDirectoryMenu = uimenu(app.DirOperationsMenu);
            app.ShowCurrentDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @select_show_current_dir, true);
            app.ShowCurrentDirectoryMenu.Text = 'Show Current Directory';
            app.ChangeWorkingDirectoryMenu = uimenu(app.DirOperationsMenu);
            app.ChangeWorkingDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @select_change_work_dir, true);
            app.ChangeWorkingDirectoryMenu.Text = 'Change Working Directory';
            app.CurrentItemTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentItemTextAreaLabel.Text = 'Current Item';
            app.CurrentItemTextArea = uitextarea(app.UIFigure);
            app.CurrentItemTextArea.Editable = 'off';
            app.INFOTextAreaLabel = uilabel(app.UIFigure);
            app.INFOTextAreaLabel.Text = 'INFO';
            app.INFOTextArea = uitextarea(app.UIFigure);
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @ModuleSelectionChanged, true);
            app.ModuleListLabel = uilabel(app.UIFigure);
            app.ModuleListLabel.Text = 'Module List';
            app.Tree2 = uitree(app.UIFigure);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @ModuleOptSelectionChanged, true);
            app.CurrentModuleLabel = uilabel(app.UIFigure);
            app.CurrentModuleLabel.Text = 'Current Module';
            app.SpecifyButton = uibutton(app.UIFigure, 'push');
            app.SpecifyButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyButtonPushed, true);
            app.SpecifyButton.Text = 'Specify';
            app.Tree3 = uitree(app.UIFigure);
            app.Tree3.SelectionChangedFcn = createCallbackFcn(app, @select_module_opt, true);
            app.CurrentItemLabel = uilabel(app.UIFigure);
            app.CurrentItemLabel.Text = 'Current Item';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.UpMenu = uimenu(app.ContextMenu);
            app.UpMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_up, true);
            app.UpMenu.Text = 'Up';
            app.DownMenu = uimenu(app.ContextMenu);
            app.DownMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_down, true);
            app.DownMenu.Text = 'Down';
            app.RemoveMenu = uimenu(app.ContextMenu);
            app.RemoveMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_remove, true);
            app.RemoveMenu.Text = 'Remove';
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @select_context_menu_copy, true);
            app.CopyMenu.Text = 'Copy';
            % Assign app.ContextMenu
            app.Tree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = octfrangi_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end